classdef check_shiftCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        AxesOverlay           matlab.ui.control.UIAxes
        ContinueButton        matlab.ui.control.Button
        ZslicesSlider_3Label  matlab.ui.control.Label
        Zslices               matlab.ui.control.Slider
        ReturnButton          matlab.ui.control.Button
        ShiftcorrectionperformanceGaugeLabel  matlab.ui.control.Label
        ShiftcorrectionperformanceGauge  matlab.ui.control.LinearGauge
        AdviceLabel           matlab.ui.control.Label
        CancelButton          matlab.ui.control.Button
        Image2                matlab.ui.control.Image
        TimepointSpinner      matlab.ui.control.Spinner
        CopyrightC2019UniversidaddeSevillaLabel  matlab.ui.control.Label
        CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, store_path, folder_names, experiment_name)
            movegui(app.UIFigure,"center");
            %Read the channels
            folders = dir([store_path filesep experiment_name filesep folder_names.shift_corrected]);
            folders = folders(3:end);
            
            %Create a flag to know if there are fibers
            flag_fibers = false;
            for ii = 1:length(folders)
                flag_fibers = strcmp(folders(ii).name, 'fibers') && folders(ii).isdir;
                if flag_fibers
                    break;                  
                end
            end
            
            %Get the files 
            if flag_fibers                
                files = dir([store_path filesep experiment_name filesep folder_names.shift_corrected filesep 'fibers']);
            else
                files = dir([store_path filesep experiment_name filesep folder_names.shift_corrected filesep 'beads']);
            end
            files = files(3:end);   
            
            %Adapt the time point scroller (one less since I don't want to
            %include the reference time point)
            if length(files)>2
                app.TimepointSpinner.Limits = [1,length(files)-1];
            else
                app.TimepointSpinner.Visible = "off";
                app.TimepointSpinner.Value = 1;
            end
            % Load the selected time point
            tp = app.TimepointSpinner.Value;
            im = load([files(tp).folder filesep files(tp).name]);
            
            %Load the reference timepoint
            imR = load([files(end).folder filesep files(end).name]);
            
            %Adapt the zslice scroller
            app.Zslices.Limits = [1 size(im.im_corr,3)];


            setappdata(0,'im',im.im_corr);
            setappdata(0,'imR',imR.im_corr);
            setappdata(0,'flag_fibers',flag_fibers);
            setappdata(0,'files',files);
            setappdata(0,'store_path',store_path)
            setappdata(0,'experiment_name',experiment_name)
            setappdata(0,'folder_names',folder_names)
            
            slice = round(app.Zslices.Value);
            %Create an overlayed image
            tmp = imfuse(imR.im_corr(:,:,slice),im.im_corr(:,:,slice),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);           
          
            %Read the similarity metric and if it doesn't exist, compute it.            
            if tp<10
                read_file_name = [store_path filesep experiment_name filesep folder_names.elastix_calc filesep 'elastixFindShift' filesep 'elastixOutput_tp_0' num2str(tp) filesep 'IterationInfo.0.R2.txt'];
            else
                read_file_name = [store_path filesep experiment_name filesep folder_names.elastix_calc filesep 'elastixFindShift' filesep 'elastixOutput_tp_' num2str(tp) filesep 'IterationInfo.0.R2.txt'];
            end            
            if exist(read_file_name,'file')
                txt_info = readMetrics(read_file_name);
                metric_value = txt_info.Metric(end);
                metric_value = - metric_value*100;
                clear txt_info;
            else
                %Compute the similarity metric
                metric_value = ssim(im.im_corr,imR.im_corr)*100;
            end
            
            %Plot the similarity metric and give advice
            if metric_value>=40
                app.AdviceLabel.Text = compose(['Advice: \n It looks like the shift correction performed well enough. You can: \n - Verify that there is a good overlay between the images (mostly yellow) \n - Continue!']);
            else
                app.AdviceLabel.Text = compose(['Advice: \n The shift correction metric seems a bit low. You can: \n - Check for a good overlay (mostly yellow) and if reasonable, continue. \n - Check for aberrations in your images. \n - Return to the previous step and try a different shift correction approach.']);
            end        
            app.ShiftcorrectionperformanceGauge.Value = metric_value;
            
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
             assignin('base','flag_need_shift_correction',false);             
             UIFigureCloseRequest(app, event);
        end

        % Callback function: ReturnButton, UIFigure
        function UIFigureCloseRequest(app, event)
            
            delete(app)            
        end

        % Value changed function: Zslices
        function ZslicesValueChanged(app, event)
            slice = round(app.Zslices.Value);
            im = getappdata(0,'im');
            imR = getappdata(0,'imR');
            %Create an overlayed image
            tmp = imfuse(imR(:,:,slice),im(:,:,slice),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);
        end

        % Callback function
        function SkipButtonPushed(app, event)
            UIFigureCloseRequest(app, event);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            assignin('base','flag_need_shift_correction',nan);
            UIFigureCloseRequest(app, event);
        end

        % Value changing function: Zslices
        function ZslicesValueChanging(app, event)
            changingValue = round(event.Value);           
            im = getappdata(0,'im');
            imR = getappdata(0,'imR');
            %Create an overlayed image
            tmp = imfuse(imR(:,:,changingValue),im(:,:,changingValue),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;    
            
            %Compute the new scrolling value
            new_val = round(app.Zslices.Value - verticalScrollCount);
            %Check that is still within the allowed limits
            if  ismember(new_val,app.Zslices.Limits(1):app.Zslices.Limits(2))
                app.Zslices.Value = new_val;
            end
            %Make the slices change
            ZslicesValueChanged(app,event);
        end

        % Value changed function: TimepointSpinner
        function TimepointSpinnerValueChanged(app, event)
            %Get the reference time point
            imR = getappdata(0,'imR');         
            
            %Get other stuff:
            files = getappdata(0,'files');   
            store_path = getappdata(0,'store_path');  
            experiment_name = getappdata(0,'experiment_name');  
            folder_names = getappdata(0,'folder_names');  
            
            %Get the pime point value
            tp = app.TimepointSpinner.Value;
            
            %load the timepoint
            im = load([files(tp).folder filesep files(tp).name]);
            im = im.im_corr;
            setappdata(0,'im',im);
            
            %Get the slice number
            slice = round(app.Zslices.Value);
            
            %Create an overlayed image
            tmp = imfuse(imR(:,:,slice),im(:,:,slice),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);   
            
            %Read the similarity metric and if it doesn't exist, compute it.            
            if tp<10
                read_file_name = [store_path filesep experiment_name filesep folder_names.elastix_calc filesep 'elastixFindShift' filesep 'elastixOutput_tp_0' num2str(tp) filesep 'IterationInfo.0.R2.txt'];
            else
                read_file_name = [store_path filesep experiment_name filesep folder_names.elastix_calc filesep 'elastixFindShift' filesep 'elastixOutput_tp_' num2str(tp) filesep 'IterationInfo.0.R2.txt'];
            end            
            if exist(read_file_name,'file')
                txt_info = readMetrics(read_file_name);
                metric_value = txt_info.Metric(end);
                metric_value = - metric_value*100;
                clear txt_info;
            else
                %Compute the similarity metric
                metric_value = ssim(im,imR)*100;
            end
            
            %Plot the similarity metric and give advice
            if metric_value>=40
                app.AdviceLabel.Text = compose(['Advice: \n It looks like the shift correction performed well enough. You can: \n - Verify that there is a good overlay between the images (mostly yellow) \n - Continue!']);
            else
                app.AdviceLabel.Text = compose(['Advice: \n The shift correction metric seems a bit low. You can: \n - Check for a good overlay (mostly yellow) and if reasonable, continue. \n - Check for aberrations in your images. \n - Return to the previous step and try a different shift correction approach.']);
            end        
            app.ShiftcorrectionperformanceGauge.Value = metric_value;            
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.AxesOverlay = uiaxes(app.UIFigure);
            title(app.AxesOverlay, 'Overlay of relaxed time point (red) with the selected time point:')
            xlabel(app.AxesOverlay, '')
            ylabel(app.AxesOverlay, '')
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Tooltip = {'Start the shift correction.'};
            app.ContinueButton.Text = 'Continue....';
            app.ZslicesSlider_3Label = uilabel(app.UIFigure);
            app.ZslicesSlider_3Label.Text = 'Z-slices';
            app.Zslices = uislider(app.UIFigure);
            app.Zslices.Limits = [1 5];
            app.Zslices.ValueChangedFcn = createCallbackFcn(app, @ZslicesValueChanged, true);
            app.Zslices.ValueChangingFcn = createCallbackFcn(app, @ZslicesValueChanging, true);
            app.Zslices.MinorTicks = [];
            app.Zslices.Tooltip = {'Use the mouse wheel to scroll through the image slices.'};
            app.Zslices.Value = 1;
            app.ReturnButton = uibutton(app.UIFigure, 'push');
            app.ReturnButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ReturnButton.Tooltip = {'Go back to the previous step.'};
            app.ReturnButton.Text = '<< Return';
            app.ShiftcorrectionperformanceGaugeLabel = uilabel(app.UIFigure);
            app.ShiftcorrectionperformanceGaugeLabel.Text = 'Shift correction performance (%):';
            app.ShiftcorrectionperformanceGauge = uigauge(app.UIFigure, 'linear');
            app.ShiftcorrectionperformanceGauge.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ShiftcorrectionperformanceGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.ShiftcorrectionperformanceGauge.Tooltip = {'This bar indicates the similarity metric between the two images.'};
            app.ShiftcorrectionperformanceGauge.Value = 100;
            app.AdviceLabel = uilabel(app.UIFigure);
            app.AdviceLabel.Text = 'Advice: ';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Close the program. Your previous results will remain in your selected output folder.'};
            app.CancelButton.Text = 'Cancel';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'tfm_lab_logo.png';
            app.TimepointSpinner = uispinner(app.UIFigure);
            app.TimepointSpinner.Limits = [1 Inf];
            app.TimepointSpinner.ValueChangedFcn = createCallbackFcn(app, @TimepointSpinnerValueChanged, true);
            app.TimepointSpinner.Tooltip = {'Check other time points'};
            app.TimepointSpinner.Value = 1;
            app.CopyrightC2019UniversidaddeSevillaLabel = uilabel(app.UIFigure);
            app.CopyrightC2019UniversidaddeSevillaLabel.Text = 'Copyright (C) 2020 Escuela Técnica Superior de Ingeniería, Universidad de Sevilla (Spain)';
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel = uilabel(app.UIFigure);
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel.Text = 'Copyright (C) 2020 Department of Mechanical Engineering, Biomechanics section, KU Leuven (Belgium)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = check_shiftCorrection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end