classdef set_dispCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisplacementmeasurementparametersPanel  matlab.ui.container.Panel
        GridspacingXYumSpinnerLabel     matlab.ui.control.Label
        GridspacingXYumSpinner          matlab.ui.control.Spinner
        GridspacingZumSpinnerLabel      matlab.ui.control.Label
        GridspacingZumSpinner           matlab.ui.control.Spinner
        UsecellmaskCheckBox             matlab.ui.control.CheckBox
        StrategyLabel                   matlab.ui.control.Label
        StrategyDropDown                matlab.ui.control.DropDown
        ArtifactcorrectionCheckBox      matlab.ui.control.CheckBox
        TimesteponlyforsequentialSpinnerLabel  matlab.ui.control.Label
        TimesteponlyforsequentialSpinner  matlab.ui.control.Spinner
        MetricDropDownLabel             matlab.ui.control.Label
        MetricDropDown                  matlab.ui.control.DropDown
        OmptimizerLabel                 matlab.ui.control.Label
        OmptimizerDropDown              matlab.ui.control.DropDown
        SkipButton                      matlab.ui.control.Button
        SkipButton_2                    matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        MarkertypeDropDownLabel         matlab.ui.control.Label
        MarkertypeDropDown              matlab.ui.control.DropDown
        GetJacobianmatrixCheckBox       matlab.ui.control.CheckBox
        NumberofiterationsSpinnerLabel  matlab.ui.control.Label
        NumberofiterationsSpinner       matlab.ui.control.Spinner
        Image2                          matlab.ui.control.Image
        CopyrightC2019UniversidaddeSevillaLabel  matlab.ui.control.Label
        CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, store_path, folder_names, experiment_name, dispParam)
            movegui(app.UIFigure,"center");
            %Access the files in the folder
            folders = dir([store_path filesep experiment_name filesep folder_names.shift_corrected]);
            folders = folders(3:end);
            %Loop through the folders to get which channels we have
            %available
            for ii = 1:length(folders)
                if folders(ii).isdir && ( strcmp(folders(ii).name,folder_names.beads) || strcmp(folders(ii).name,folder_names.fibers) )
                    app.MarkertypeDropDown.Items{end+1} = folders(ii).name;
                end            
            end
            %Check if both markers are present and if they are, add the
            %option of selecting both
            if length(app.MarkertypeDropDown.Items) == 2
                app.MarkertypeDropDown.Items{end+1} = 'both';
            end
            
            %Make a global variable with the deafult displacement
            %calculation parameters
            setappdata(0,'dispParam',dispParam);
        end

        % Button pushed function: SkipButton_2
        function SkipButton_2Pushed(app, event)
            assignin('base','need_to_load','parameters');
            UIFigureCloseRequest(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            assignin('base','need_to_load','images');
            UIFigureCloseRequest(app, event);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            %Load the deafult parameters
            dispParam = getappdata(0,'dispParam');
            
            %Change the user parameters
            dispParam.grid.minSpacing.xy = app.GridspacingXYumSpinner.Value; % units in um (positive integer number )- average distance between two beads approx
            dispParam.grid.minSpacing.z = app.GridspacingZumSpinner.Value; % units in um (only needed for 3D Analysis -- positive integer number)
           
            dispParam.mask.cell = app.UsecellmaskCheckBox.Value; % true or false - always leave true, ignores the beads inside the mask of the cell
            dispParam.strategy = app.StrategyDropDown.Value; % 'direct' or 'sequential' or 'seqBackProp' or 'bwdIniTf'
            dispParam.artifactCorr = app.ArtifactcorrectionCheckBox.Value; % true, false
            
            dispParam.seq.tStep = app.TimesteponlyforsequentialSpinner.Value; % Only valid if (dispParam.strategy = 'seqBackProp' or 'sequential') you can define timestep for comparing sequential data and choosing reference state
            dispParam.metric.type = app.MetricDropDown.Value; % 'normXCorr' or 'mutualInfo' or 'meanSquares'
            dispParam.optim.method = app.OmptimizerDropDown.Value; % 'qnLBFGS' or 'adapStochGradDesc'
            dispParam.optim.iterNum = app.NumberofiterationsSpinner.Value * ones(1,3);
            
            dispParam.marker_type = app.MarkertypeDropDown.Value;
            
            dispParam.jacobMatrixFlag = app.GetJacobianmatrixCheckBox.Value;


            assignin('base','need_to_load','');
            assignin('base','dispParam',dispParam);
            
            UIFigureCloseRequest(app, event);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DisplacementmeasurementparametersPanel = uipanel(app.UIFigure);
            app.DisplacementmeasurementparametersPanel.Title = 'Displacement measurement parameters:';
            app.GridspacingXYumSpinnerLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.GridspacingXYumSpinnerLabel.Text = 'Grid spacing XY (um):';
            app.GridspacingXYumSpinner = uispinner(app.DisplacementmeasurementparametersPanel);
            app.GridspacingXYumSpinner.Limits = [1 Inf];
            app.GridspacingXYumSpinner.Tooltip = {'Size in microns of the non-rigid image registration grid in XY. '; 'Recommended: 5 microns.'};
            app.GridspacingXYumSpinner.Value = 5;
            app.GridspacingZumSpinnerLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.GridspacingZumSpinnerLabel.Text = 'Grid spacing Z (um):';
            app.GridspacingZumSpinner = uispinner(app.DisplacementmeasurementparametersPanel);
            app.GridspacingZumSpinner.Limits = [1 Inf];
            app.GridspacingZumSpinner.Tooltip = {'Size in pixels/voxels of the non-rigid image registration grid in Z. It will not be used for 2D cases.'; 'Recommended: equivalent to 5 microns.'};
            app.GridspacingZumSpinner.Value = 5;
            app.UsecellmaskCheckBox = uicheckbox(app.DisplacementmeasurementparametersPanel);
            app.UsecellmaskCheckBox.Tooltip = {'(Recommended) Use the cell mask to ignore the beads engulfed by the cell for image registration.'};
            app.UsecellmaskCheckBox.Text = 'Use cell mask';
            app.UsecellmaskCheckBox.Value = true;
            app.StrategyLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.StrategyLabel.Enable = 'off';
            app.StrategyLabel.Text = 'Strategy:';
            app.StrategyDropDown = uidropdown(app.DisplacementmeasurementparametersPanel);
            app.StrategyDropDown.Items = {'direct', 'sequential (not implemented)'};
            app.StrategyDropDown.Enable = 'off';
            app.StrategyDropDown.Tooltip = {'Image registration strategy:'; 'direct: takes the relaxed time point as a reference.'; 'sequential: computes relative displacements between time points separated a certain time step.'};
            app.StrategyDropDown.Value = 'direct';
            app.ArtifactcorrectionCheckBox = uicheckbox(app.DisplacementmeasurementparametersPanel);
            app.ArtifactcorrectionCheckBox.Enable = 'off';
            app.ArtifactcorrectionCheckBox.Tooltip = {'Perform artifact correction after displacement calculation as shown in Jorge Penas (2017) Biomaterials'};
            app.ArtifactcorrectionCheckBox.Text = 'Artifact correction';
            app.TimesteponlyforsequentialSpinnerLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.TimesteponlyforsequentialSpinnerLabel.Enable = 'off';
            app.TimesteponlyforsequentialSpinnerLabel.Text = 'Time step (only for sequential): ';
            app.TimesteponlyforsequentialSpinner = uispinner(app.DisplacementmeasurementparametersPanel);
            app.TimesteponlyforsequentialSpinner.Limits = [1 Inf];
            app.TimesteponlyforsequentialSpinner.Editable = 'off';
            app.TimesteponlyforsequentialSpinner.Enable = 'off';
            app.TimesteponlyforsequentialSpinner.Tooltip = {'Time step size for the sequential displacement calculation.'};
            app.TimesteponlyforsequentialSpinner.Value = 10;
            app.MetricDropDownLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.MetricDropDownLabel.Text = 'Metric:';
            app.MetricDropDown = uidropdown(app.DisplacementmeasurementparametersPanel);
            app.MetricDropDown.Items = {'normXCorr', 'mutualInfo', 'meanSquares'};
            app.MetricDropDown.Tooltip = {'Image registration metric.'};
            app.MetricDropDown.Value = 'normXCorr';
            app.OmptimizerLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.OmptimizerLabel.Text = 'Omptimizer:';
            app.OmptimizerDropDown = uidropdown(app.DisplacementmeasurementparametersPanel);
            app.OmptimizerDropDown.Items = {'adapStochGradDesc', 'qnLBFGS'};
            app.OmptimizerDropDown.Tooltip = {'Image registration optimizer'};
            app.OmptimizerDropDown.Value = 'adapStochGradDesc';
            app.SkipButton = uibutton(app.DisplacementmeasurementparametersPanel, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Tooltip = {'Skip the manual parameter selection by selecting existing filtered images.'};
            app.SkipButton.Text = 'Skip this step >>';
            app.SkipButton_2 = uibutton(app.DisplacementmeasurementparametersPanel, 'push');
            app.SkipButton_2.ButtonPushedFcn = createCallbackFcn(app, @SkipButton_2Pushed, true);
            app.SkipButton_2.Tooltip = {'Load an existing parameter file.'};
            app.SkipButton_2.Text = 'Select predefined parameters...';
            app.StartButton = uibutton(app.DisplacementmeasurementparametersPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Run the non-rigid image registration and displacement calculation.'};
            app.StartButton.Text = 'Start';
            app.MarkertypeDropDownLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.MarkertypeDropDownLabel.Text = 'Marker type:';
            app.MarkertypeDropDown = uidropdown(app.DisplacementmeasurementparametersPanel);
            app.MarkertypeDropDown.Items = {'beads', 'fibers', 'both (choose only if both channels exist!)'};
            app.MarkertypeDropDown.Tooltip = {'Type of marker that is to be used for the registration.'; ''; 'If ''both'' is selected'; ' the displacements will be computed first for one type of marker'; ' and then for the other.'};
            app.MarkertypeDropDown.Value = 'beads';
            app.GetJacobianmatrixCheckBox = uicheckbox(app.DisplacementmeasurementparametersPanel);
            app.GetJacobianmatrixCheckBox.Tooltip = {'Get the analytical Jacobian matrix of the displacement field. It is useful to compute strains'; ' but it is a very large tensor.'};
            app.GetJacobianmatrixCheckBox.Text = 'Get Jacobian matrix';
            app.NumberofiterationsSpinnerLabel = uilabel(app.DisplacementmeasurementparametersPanel);
            app.NumberofiterationsSpinnerLabel.Text = 'Number of iterations:';
            app.NumberofiterationsSpinner = uispinner(app.DisplacementmeasurementparametersPanel);
            app.NumberofiterationsSpinner.Step = 50;
            app.NumberofiterationsSpinner.Limits = [1 Inf];
            app.NumberofiterationsSpinner.Tooltip = {'Number of iterations per sclae for the non-rigid image registration.'};
            app.NumberofiterationsSpinner.Value = 500;
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'tfm_lab_logo.png';
            app.CopyrightC2019UniversidaddeSevillaLabel = uilabel(app.UIFigure);
            app.CopyrightC2019UniversidaddeSevillaLabel.Text = 'Copyright (C) 2020 Escuela Técnica Superior de Ingeniería, Universidad de Sevilla (Spain)';
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel = uilabel(app.UIFigure);
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel.Text = 'Copyright (C) 2020 Department of Mechanical Engineering, Biomechanics section, KU Leuven (Belgium)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_dispCalc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end