classdef set_imProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        beadsTab                     matlab.ui.container.Tab
        ParametersPanel_3            matlab.ui.container.Panel
        var1Label                    matlab.ui.control.Label
        var1Beads                    matlab.ui.control.Spinner
        var2Label                    matlab.ui.control.Label
        var2Beads                    matlab.ui.control.Spinner
        weightLabel                  matlab.ui.control.Label
        weightBeads                  matlab.ui.control.Spinner
        smoothvarLabel               matlab.ui.control.Label
        smoothvarBeads               matlab.ui.control.Spinner
        contrastStretchHighBeads     matlab.ui.control.Spinner
        contraststrechminmaxLabel    matlab.ui.control.Label
        contrastStretchLowBeads      matlab.ui.control.Spinner
        cellTab                      matlab.ui.container.Tab
        ParametersPanel              matlab.ui.container.Panel
        Panel_2                      matlab.ui.container.Panel
        LightendarkregionsCell       matlab.ui.control.CheckBox
        expvalSpinnerLabel           matlab.ui.control.Label
        expvalCell                   matlab.ui.control.Spinner
        methodDropDownLabel          matlab.ui.control.Label
        methodCell                   matlab.ui.control.DropDown
        Panel_3                      matlab.ui.container.Panel
        smoothvarLabel_2             matlab.ui.control.Label
        smoothvarCell                matlab.ui.control.Spinner
        contrastStretchHighCell      matlab.ui.control.Spinner
        contraststrechminmaxLabel_2  matlab.ui.control.Label
        contrastStretchLowCell       matlab.ui.control.Spinner
        cellSegTab                   matlab.ui.container.Tab
        ParametersCellSeg            matlab.ui.container.Panel
        thresholdreductionLabel      matlab.ui.control.Label
        thRedCellSeg                 matlab.ui.control.Spinner
        minimumcelllsizepixelsvoxelsLabel  matlab.ui.control.Label
        minSizeCellSeg               matlab.ui.control.Spinner
        AdditionalvisualizationtoolsPanel  matlab.ui.container.Panel
        Render3D                     matlab.ui.control.Button
        OverlaySegmentation          matlab.ui.control.Button
        fibersTab                    matlab.ui.container.Tab
        ParametersPanel_2            matlab.ui.container.Panel
        Panel_4                      matlab.ui.container.Panel
        LightendarkregionsFibers     matlab.ui.control.CheckBox
        expvalSpinnerLabel_2         matlab.ui.control.Label
        expvalFibers                 matlab.ui.control.Spinner
        methodDropDownLabel_2        matlab.ui.control.Label
        methodFibers                 matlab.ui.control.DropDown
        Panel_5                      matlab.ui.container.Panel
        smoothvarLabel_3             matlab.ui.control.Label
        smoothvarFibers              matlab.ui.control.Spinner
        contrastStretchHighFibers    matlab.ui.control.Spinner
        contraststrechminmaxLabel_3  matlab.ui.control.Label
        contrastStretchLowFibers     matlab.ui.control.Spinner
        SkipButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        ZslicesSlider_3Label         matlab.ui.control.Label
        Zslices                      matlab.ui.control.Slider
        AxesOriginal                 matlab.ui.control.UIAxes
        AxesFiltered                 matlab.ui.control.UIAxes
        Visualize                    matlab.ui.control.Button
        ZprojectOriginal             matlab.ui.control.Button
        ZprojectFiltered             matlab.ui.control.Button
        SkipButton_2                 matlab.ui.control.Button
        Image2_2                     matlab.ui.control.Image
        CopyrightC2019UniversidaddeSevillaLabel  matlab.ui.control.Label
        CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, store_path, folder_names, experiment_name)
            movegui(app.UIFigure,"center");
            %Read the channels
            folders = dir([store_path filesep experiment_name filesep folder_names.tiff_raw]);
            folders = folders(3:end);
            
            %Create flags to know which channels we have and store the non
            %filtered images
            flags.delete_cell = true;
            flags.delete_beads = true;
            flags.delete_fibers = true;
            for ii = 1:length(folders)
                if folders(ii).isdir
                    im = read3Dim([store_path filesep experiment_name filesep folder_names.tiff_raw filesep folders(ii).name filesep 'tp_01.tif']);
                    switch folders(ii).name
                        case 'cell'
                            %Variables for cell filtering
                            setappdata(0,'im_cell_noFilt',im);
                            setappdata(0,'im_cell_filt',[]);
                            %Variables for cell segmentation
                            setappdata(0,'im_cellSeg_noFilt',im); %This will eventually be the filtered cell
                            setappdata(0,'im_cellSeg_filt',[]); %This will be the cell mask
                            
                            flags.delete_cell = false;
                        case 'beads'
                            setappdata(0,'im_beads_noFilt',im);
                            setappdata(0,'im_beads_filt',[]);
                            
                            flags.delete_beads = false;
                        case 'fibers'
                            setappdata(0,'im_fibers_noFilt',im);
                            setappdata(0,'im_fibers_filt',[]);
                            
                            flags.delete_fibers = false;
                    end
                    
                    %Plot the image
                    if strcmp(app.TabGroup.SelectedTab.Title,folders(ii).name)
                        imshow(im(:,:,round(app.Zslices.Value)),[],'Parent',app.AxesOriginal);
                    end
                end
            end
            app.Zslices.Limits = [1 size(im,3)];
            clear im;              
            
            
            %Delete the tabs that are not needed
            if flags.delete_cell
                delete(app.cellTab);
                delete(app.cellSegTab);
            end
            if flags.delete_beads
                delete(app.beadsTab);
            end
            if flags.delete_fibers
                delete(app.fibersTab);
            end
            %Update the flags
            setappdata(0,'flags',flags);  
            setappdata(0,'folder_names',folder_names);  
            
        end

        % Value changed function: Zslices
        function ZslicesValueChanged(app, event)
            slice = round(app.Zslices.Value);   
            current_channel = app.TabGroup.SelectedTab.Title;
            plotSlices(app.AxesOriginal,app.AxesFiltered,current_channel,slice);            
        end

        % Button pushed function: Visualize
        function VisualizePushed(app, event)
            current_channel = app.TabGroup.SelectedTab.Title;
            if strcmp(current_channel,'cellSeg')
                d = uiprogressdlg(app.UIFigure,'Title','Segmenting image...',...
                    'Indeterminate','on');
            else
                d = uiprogressdlg(app.UIFigure,'Title','Filtering image...',...
                    'Indeterminate','on');
            end
            im_noFilt = getappdata(0,['im_',current_channel,'_noFilt']);
            switch current_channel
                case 'beads'
                    im_filt = beadFilter(im_noFilt,app.var1Beads.Value,app.var2Beads.Value,app.weightBeads.Value,app.smoothvarBeads.Value,app.contrastStretchLowBeads.Value,app.contrastStretchHighBeads.Value);
                    setappdata(0,'im_beads_filt',im_filt); 
                case 'cell'
                    lightenDarkRegions.flag = app.LightendarkregionsCell.Value;
                    lightenDarkRegions.method = app.methodCell.Value;
                    lightenDarkRegions.expVal = app.expvalCell.Value;
                    im_filt = cellFilter(im_noFilt,lightenDarkRegions,app.smoothvarCell.Value,app.contrastStretchLowCell.Value,app.contrastStretchHighCell.Value);                    
                    setappdata(0,'im_cell_filt',im_filt); 
                    %Pass the result to the cellSeg section
                    setappdata(0,'im_cellSeg_noFilt',im_filt); 
                case 'cellSeg'    
                    %We take the filtered cell as noFiltered for code
                    %simplification purpuses                    
                    cellsegParam.thresholdMethod =  'otsu';
                    cellsegParam.thPercentReduct = app.thRedCellSeg.Value;             
                    cellsegParam.minCellSize = app.minSizeCellSeg.Value; 
                    cellsegParam.smoothCellMask = false;
                    im_filt = cellSegmentation(im_noFilt,cellsegParam);
                    im_filt = im2mat(im_filt);    
                    setappdata(0,'im_cellSeg_filt',im_filt); 
                case 'fibers'
                    lightenDarkRegions.flag = app.LightendarkregionsFibers.Value;
                    lightenDarkRegions.method = app.methodFibers.Value;
                    lightenDarkRegions.expVal = app.expvalFibers.Value;
                    [im_filt,~] = fiberFilter(im_noFilt,lightenDarkRegions,app.smoothvarFibers.Value,app.contrastStretchLowFibers.Value,app.contrastStretchHighFibers.Value);
                    setappdata(0,'im_fibers_filt',im_filt); 
            end
            slice = app.Zslices.Value;
            imshow(im_noFilt(:,:,round(slice)),[],'Parent',app.AxesOriginal);
            if strcmp(current_channel,'cellSeg')
                tmp = labeloverlay(im_noFilt(:,:,round(slice)),im_filt(:,:,round(slice)),'Transparency',0.8);
                imshow(tmp,[],'Parent',app.AxesFiltered);   
            else
                imshow(im_filt(:,:,round(slice)),[],'Parent',app.AxesFiltered);                
            end           
            
            
            % close the dialog box
            close(d);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            %Get the flags to know which channels we have
            flags = getappdata(0,'flags');
            
            %Initiate the variables 
            filtParam = [];
            cellfiltParam = [];
            cellsegParam = [];
            
            if not(flags.delete_beads)      
                %Bead filtering parameters
                filtParam.beads.var1 = app.var1Beads.Value;
                filtParam.beads.var2 = app.var2Beads.Value;
                filtParam.beads.weight = app.weightBeads.Value;
                filtParam.beads.smoothVar = app.smoothvarBeads.Value;
                filtParam.beads.stretchLB = app.contrastStretchLowBeads.Value; 
                filtParam.beads.stretchUB = app.contrastStretchHighBeads.Value; 
            end
            
            if not(flags.delete_cell)
                %Cell filtering parameters
                cellfiltParam.smoothVal = app.smoothvarCell.Value;
                cellfiltParam.lightenDarkRegions.flag = app.LightendarkregionsCell.Value;
                cellfiltParam.lightenDarkRegions.method = app.methodCell.Value;
                cellfiltParam.lightenDarkRegions.expVal = app.expvalCell.Value;
                cellfiltParam.stretchLB = app.contrastStretchLowCell.Value;
                cellfiltParam.stretchUB = app.contrastStretchHighCell.Value;                
                
                %Cell segmentation parameters
                cellsegParam.thresholdMethod =  'otsu';                
                cellsegParam.thPercentReduct = app.thRedCellSeg.Value;     
                cellsegParam.minCellSize = app.minSizeCellSeg.Value;                
                cellsegParam.smoothCellMask = false;                
            end
            
            if not(flags.delete_fibers)
                %Fiber filtering parameters
                filtParam.fibers.lightenDarkRegions.flag = app.LightendarkregionsFibers.Value;               
                filtParam.fibers.lightenDarkRegions.method = app.methodFibers.Value; 
                filtParam.fibers.lightenDarkRegions.expVal = app.expvalFibers.Value;            
                filtParam.fibers.smoothVal = app.smoothvarFibers.Value; 
                filtParam.fibers.stretchLB = app.contrastStretchLowFibers.Value; 
                filtParam.fibers.stretchUB = app.contrastStretchHighFibers.Value;             
                
            end            
            
            %Pass the variables to the workspace
            assignin('base','filtParam',filtParam);
            assignin('base','cellsegParam',cellsegParam);
            assignin('base','cellfiltParam',cellfiltParam);
            
            %Nothing needs to be loaded externally
            assignin('base','need_to_load','');
            
            UIFigureCloseRequest(app, event);
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            assignin('base','need_to_load','images');
            UIFigureCloseRequest(app, event);
        end

        % Button pushed function: ZprojectOriginal
        function ZprojectOriginalButtonPushed(app, event)
            current_channel = app.TabGroup.SelectedTab.Title;
            im_noFilt = getappdata(0,['im_',current_channel,'_noFilt']);             
            figure
            imshow(max(im_noFilt,[],3),[]);
            title([current_channel ' original channel: Maximum Z projection']);
            set(gca,'FontName','Segoe UI Light')
        end

        % Button pushed function: ZprojectFiltered
        function ZprojectFilteredButtonPushed(app, event)
            current_channel = app.TabGroup.SelectedTab.Title;
            im_filt = getappdata(0,['im_',current_channel,'_filt']);             
            figure
            imshow(max(im_filt,[],3),[]);
            title([current_channel ' filtered channel: Maximum Z projection']);
            set(gca,'FontName','Segoe UI Light')
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            ZslicesValueChanged(app,event);   
            current_channel = app.TabGroup.SelectedTab.Title;
            if strcmp(current_channel,'cellSeg')
                app.AxesOriginal.Title.String = 'Filtered';
                app.AxesFiltered.Title.String = 'Segmented';
            else
                app.AxesOriginal.Title.String = 'Original';  
                app.AxesFiltered.Title.String = 'Filtered';
            end
        end

        % Button pushed function: Render3D
        function Render3DButtonPushed(app, event)
            current_channel = app.TabGroup.SelectedTab.Title;
            folder_names = getappdata(0,'folder_names');
            res = getResolution(folder_names.tiff_raw);
            d = uiprogressdlg(app.UIFigure,'Title','Rendering cell segmentation...',...
                'Indeterminate','on');
            figure;
            set(gcf,'Color',[1,1,1]);
            im = getappdata(0,['im_',current_channel,'_filt']);
            isosurface(im,0.5);
            daspect(res/min(res));
            box on
            camlight
            lighting gouraud
            boxColor = 0.7*ones(1,3);
            set(gca,'XColor',boxColor,'YColor',boxColor,'ZColor',boxColor);
            set(gca,'xtick',[],'ytick',[],'ztick',[]);
            set(gca,'BoxStyle','full');
            s = size(im);
            axis([1 s(2) 1 s(1) 1 s(3)]);
            title(['3D render of ',current_channel]);
            % close the dialog box
            close(d);
        end

        % Button pushed function: OverlaySegmentation
        function OverlaySegmentationButtonPushed(app, event)
            current_channel = app.TabGroup.SelectedTab.Title;
            im_filt = getappdata(0,['im_',current_channel,'_filt']);    
            im_noFilt = getappdata(0,['im_',current_channel,'_noFilt']);   
            figure
            imshow(max(im_noFilt,[],3),[]);hold on;
            visboundaries(max(im_filt,[],3));       
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Button pushed function: SkipButton_2
        function SkipButton_2Pushed(app, event)
            assignin('base','need_to_load','parameters');
            UIFigureCloseRequest(app, event);
        end

        % Value changing function: Zslices
        function ZslicesValueChanging(app, event)
            changingValue = round(event.Value);           
            current_channel = app.TabGroup.SelectedTab.Title;
            plotSlices(app.AxesOriginal,app.AxesFiltered,current_channel,changingValue);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            
            %Compute the new scrolling value
            new_val = round(app.Zslices.Value - verticalScrollCount);
            %Check that is still within the allowed limits
            if  ismember(new_val,app.Zslices.Limits(1):app.Zslices.Limits(2))
                app.Zslices.Value = new_val;
            end
            %Make the slices change
            ZslicesValueChanged(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.beadsTab = uitab(app.TabGroup);
            app.beadsTab.Tooltip = {'Filter parameters for the beads channel.'};
            app.beadsTab.Title = 'beads';
            app.ParametersPanel_3 = uipanel(app.beadsTab);
            app.ParametersPanel_3.Title = 'Parameters';
            app.var1Label = uilabel(app.ParametersPanel_3);
            app.var1Label.Text = 'var1:';
            app.var1Beads = uispinner(app.ParametersPanel_3);
            app.var1Beads.Step = 0.1;
            app.var1Beads.Limits = [0 Inf];
            app.var1Beads.Tooltip = {'Variance for the first Gaussian filter of the DoG filter. (It must be lower than var2).'};
            app.var1Beads.Value = 1;
            app.var2Label = uilabel(app.ParametersPanel_3);
            app.var2Label.Text = 'var2:';
            app.var2Beads = uispinner(app.ParametersPanel_3);
            app.var2Beads.Step = 0.1;
            app.var2Beads.Limits = [0 Inf];
            app.var2Beads.Tooltip = {'Variance for the second Gaussian filter of the DoG filter. (It must be higher than var1).'};
            app.var2Beads.Value = 1.2;
            app.weightLabel = uilabel(app.ParametersPanel_3);
            app.weightLabel.Text = 'weight:';
            app.weightBeads = uispinner(app.ParametersPanel_3);
            app.weightBeads.Step = 0.1;
            app.weightBeads.Limits = [0 Inf];
            app.weightBeads.Tooltip = {'Amplitude for the second filter of the DoG filter.'};
            app.weightBeads.Value = 0.8;
            app.smoothvarLabel = uilabel(app.ParametersPanel_3);
            app.smoothvarLabel.Text = 'smooth var:';
            app.smoothvarBeads = uispinner(app.ParametersPanel_3);
            app.smoothvarBeads.Step = 0.1;
            app.smoothvarBeads.Limits = [0 Inf];
            app.smoothvarBeads.Tooltip = {'Variance for the smoothing filter.'};
            app.smoothvarBeads.Value = 0.15;
            app.contrastStretchHighBeads = uispinner(app.ParametersPanel_3);
            app.contrastStretchHighBeads.Step = 0.1;
            app.contrastStretchHighBeads.Limits = [0 100];
            app.contrastStretchHighBeads.Tooltip = {'Contrast stretching limits.'};
            app.contrastStretchHighBeads.Value = 99.99;
            app.contraststrechminmaxLabel = uilabel(app.ParametersPanel_3);
            app.contraststrechminmaxLabel.Tooltip = {'Contrast stretching limits.'};
            app.contraststrechminmaxLabel.Text = 'contrast strech [min,max]:';
            app.contrastStretchLowBeads = uispinner(app.ParametersPanel_3);
            app.contrastStretchLowBeads.Step = 0.1;
            app.contrastStretchLowBeads.Limits = [0 100];
            app.contrastStretchLowBeads.Tooltip = {'Contrast stretching limits.'};
            app.contrastStretchLowBeads.Value = 80;
            app.cellTab = uitab(app.TabGroup);
            app.cellTab.Tooltip = {'Filter parameters for the cell channel.'};
            app.cellTab.Title = 'cell';
            app.ParametersPanel = uipanel(app.cellTab);
            app.ParametersPanel.Title = 'Parameters';
            app.Panel_2 = uipanel(app.ParametersPanel);
            app.LightendarkregionsCell = uicheckbox(app.Panel_2);
            app.LightendarkregionsCell.Text = 'Lighten dark regions';
            app.expvalSpinnerLabel = uilabel(app.Panel_2);
            app.expvalSpinnerLabel.Text = 'exp. val:';
            app.expvalCell = uispinner(app.Panel_2);
            app.expvalCell.Step = 0.1;
            app.expvalCell.Limits = [0 1];
            app.expvalCell.Value = 0.5;
            app.methodDropDownLabel = uilabel(app.Panel_2);
            app.methodDropDownLabel.Text = 'method:';
            app.methodCell = uidropdown(app.Panel_2);
            app.methodCell.Items = {'root', 'log'};
            app.methodCell.Value = 'log';
            app.Panel_3 = uipanel(app.ParametersPanel);
            app.smoothvarLabel_2 = uilabel(app.Panel_3);
            app.smoothvarLabel_2.Text = 'smooth var:';
            app.smoothvarCell = uispinner(app.Panel_3);
            app.smoothvarCell.Step = 0.1;
            app.smoothvarCell.Limits = [0 Inf];
            app.smoothvarCell.Value = 0.15;
            app.contrastStretchHighCell = uispinner(app.Panel_3);
            app.contrastStretchHighCell.Step = 0.1;
            app.contrastStretchHighCell.Limits = [0 100];
            app.contrastStretchHighCell.Value = 99.99;
            app.contraststrechminmaxLabel_2 = uilabel(app.Panel_3);
            app.contraststrechminmaxLabel_2.Text = 'contrast strech [min,max]:';
            app.contrastStretchLowCell = uispinner(app.Panel_3);
            app.contrastStretchLowCell.Step = 0.1;
            app.contrastStretchLowCell.Limits = [0 100];
            app.contrastStretchLowCell.Value = 80;
            app.cellSegTab = uitab(app.TabGroup);
            app.cellSegTab.Tooltip = {'Cell segmentation parameters.'};
            app.cellSegTab.Title = 'cellSeg';
            app.ParametersCellSeg = uipanel(app.cellSegTab);
            app.ParametersCellSeg.Title = 'Parameters';
            app.thresholdreductionLabel = uilabel(app.ParametersCellSeg);
            app.thresholdreductionLabel.Text = 'threshold reduction (%):';
            app.thRedCellSeg = uispinner(app.ParametersCellSeg);
            app.thRedCellSeg.Step = 5;
            app.thRedCellSeg.Limits = [0 100];
            app.thRedCellSeg.Value = 20;
            app.minimumcelllsizepixelsvoxelsLabel = uilabel(app.ParametersCellSeg);
            app.minimumcelllsizepixelsvoxelsLabel.Text = 'minimum celll size (pixels/voxels):';
            app.minSizeCellSeg = uispinner(app.ParametersCellSeg);
            app.minSizeCellSeg.Step = 50;
            app.minSizeCellSeg.Limits = [1 Inf];
            app.minSizeCellSeg.Value = 500;
            app.AdditionalvisualizationtoolsPanel = uipanel(app.cellSegTab);
            app.AdditionalvisualizationtoolsPanel.Title = 'Additional visualization tools';
            app.Render3D = uibutton(app.AdditionalvisualizationtoolsPanel, 'push');
            app.Render3D.ButtonPushedFcn = createCallbackFcn(app, @Render3DButtonPushed, true);
            app.Render3D.Icon = '12394551611553668339-128.png';
            app.Render3D.Tooltip = {'Visualize a 3D render.'};
            app.Render3D.Text = '';
            app.OverlaySegmentation = uibutton(app.AdditionalvisualizationtoolsPanel, 'push');
            app.OverlaySegmentation.ButtonPushedFcn = createCallbackFcn(app, @OverlaySegmentationButtonPushed, true);
            app.OverlaySegmentation.Icon = '7058797381536208098-128.png';
            app.OverlaySegmentation.Tooltip = {'Visualize a 2D projection of the segmentation boundaries.'};
            app.OverlaySegmentation.Text = '';
            app.fibersTab = uitab(app.TabGroup);
            app.fibersTab.Tooltip = {'Filter parameters for the fiber channel.'};
            app.fibersTab.Title = 'fibers';
            app.ParametersPanel_2 = uipanel(app.fibersTab);
            app.ParametersPanel_2.Title = 'Parameters';
            app.Panel_4 = uipanel(app.ParametersPanel_2);
            app.LightendarkregionsFibers = uicheckbox(app.Panel_4);
            app.LightendarkregionsFibers.Text = 'Lighten dark regions';
            app.expvalSpinnerLabel_2 = uilabel(app.Panel_4);
            app.expvalSpinnerLabel_2.Text = 'exp. val:';
            app.expvalFibers = uispinner(app.Panel_4);
            app.expvalFibers.Step = 0.1;
            app.expvalFibers.Limits = [0 1];
            app.expvalFibers.Value = 0.5;
            app.methodDropDownLabel_2 = uilabel(app.Panel_4);
            app.methodDropDownLabel_2.Text = 'method:';
            app.methodFibers = uidropdown(app.Panel_4);
            app.methodFibers.Items = {'root', 'log'};
            app.methodFibers.Value = 'log';
            app.Panel_5 = uipanel(app.ParametersPanel_2);
            app.smoothvarLabel_3 = uilabel(app.Panel_5);
            app.smoothvarLabel_3.Text = 'smooth var:';
            app.smoothvarFibers = uispinner(app.Panel_5);
            app.smoothvarFibers.Step = 0.1;
            app.smoothvarFibers.Limits = [0 Inf];
            app.smoothvarFibers.Value = 0.15;
            app.contrastStretchHighFibers = uispinner(app.Panel_5);
            app.contrastStretchHighFibers.Step = 0.1;
            app.contrastStretchHighFibers.Limits = [0 100];
            app.contrastStretchHighFibers.Value = 99.99;
            app.contraststrechminmaxLabel_3 = uilabel(app.Panel_5);
            app.contraststrechminmaxLabel_3.Text = 'contrast strech [min,max]:';
            app.contrastStretchLowFibers = uispinner(app.Panel_5);
            app.contrastStretchLowFibers.Step = 0.1;
            app.contrastStretchLowFibers.Limits = [0 100];
            app.contrastStretchLowFibers.Value = 80;
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Tooltip = {'Skip the manual parameter selection by selecting existing filtered images.'};
            app.SkipButton.Text = 'Skip this step >>';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Run the filtering of all the images in the folder with the current settings.'};
            app.StartButton.Text = 'Start';
            app.ZslicesSlider_3Label = uilabel(app.UIFigure);
            app.ZslicesSlider_3Label.Text = 'Z-slices';
            app.Zslices = uislider(app.UIFigure);
            app.Zslices.Limits = [1 5];
            app.Zslices.ValueChangedFcn = createCallbackFcn(app, @ZslicesValueChanged, true);
            app.Zslices.ValueChangingFcn = createCallbackFcn(app, @ZslicesValueChanging, true);
            app.Zslices.MinorTicks = [];
            app.Zslices.Tooltip = {'Use the mouse wheel to scroll through the image slices.'};
            app.Zslices.Value = 1;
            app.AxesOriginal = uiaxes(app.UIFigure);
            title(app.AxesOriginal, 'Original')
            xlabel(app.AxesOriginal, '')
            ylabel(app.AxesOriginal, '')
            app.AxesFiltered = uiaxes(app.UIFigure);
            title(app.AxesFiltered, 'Filtered')
            xlabel(app.AxesFiltered, '')
            ylabel(app.AxesFiltered, '')
            app.Visualize = uibutton(app.UIFigure, 'push');
            app.Visualize.ButtonPushedFcn = createCallbackFcn(app, @VisualizePushed, true);
            app.Visualize.Icon = 'iconfinder_119_Eye_183245.png';
            app.Visualize.Tooltip = {'Visualize results.'};
            app.Visualize.Text = '';
            app.ZprojectOriginal = uibutton(app.UIFigure, 'push');
            app.ZprojectOriginal.ButtonPushedFcn = createCallbackFcn(app, @ZprojectOriginalButtonPushed, true);
            app.ZprojectOriginal.Icon = 'iconfinder_stack-layer-pile-element-material_3209232.png';
            app.ZprojectOriginal.Tooltip = {'Generate a Maximum Z projection of the image.'};
            app.ZprojectOriginal.Text = '';
            app.ZprojectFiltered = uibutton(app.UIFigure, 'push');
            app.ZprojectFiltered.ButtonPushedFcn = createCallbackFcn(app, @ZprojectFilteredButtonPushed, true);
            app.ZprojectFiltered.Icon = 'iconfinder_stack-layer-pile-element-material_3209232.png';
            app.ZprojectFiltered.Tooltip = {'Generate a Maximum Z projection of the image.'};
            app.ZprojectFiltered.Text = '';
            app.SkipButton_2 = uibutton(app.UIFigure, 'push');
            app.SkipButton_2.ButtonPushedFcn = createCallbackFcn(app, @SkipButton_2Pushed, true);
            app.SkipButton_2.Tooltip = {'Load an existing parameter file.'};
            app.SkipButton_2.Text = 'Select predefined parameters...';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'tfm_lab_logo.png';
            app.CopyrightC2019UniversidaddeSevillaLabel = uilabel(app.UIFigure);
            app.CopyrightC2019UniversidaddeSevillaLabel.Text = 'Copyright (C) 2020 Escuela Técnica Superior de Ingeniería, Universidad de Sevilla (Spain)';
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel = uilabel(app.UIFigure);
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel.Text = 'Copyright (C) 2020 Department of Mechanical Engineering, Biomechanics section, KU Leuven (Belgium)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_imProcessing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end