classdef set_inputs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        addColumnChannel          matlab.ui.control.Button
        tableChannels             matlab.ui.control.Table
        DefinethechannelnamesleaveemptyifyoudontwanttouseachannelLabel  matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        ZrangetobekeptseparatedbycommasLabel  matlab.ui.control.Label
        zplanes                   matlab.ui.control.EditField
        TimepointrangetobekeptseparatedbycommasEditFieldLabel  matlab.ui.control.Label
        timepoints                matlab.ui.control.EditField
        WriteLabel                matlab.ui.control.Label
        XrangetobekeptseparatedbycommasLabel  matlab.ui.control.Label
        xRange                    matlab.ui.control.EditField
        YrangetobekeptseparatedbycommasLabel  matlab.ui.control.Label
        yRange                    matlab.ui.control.EditField
        StartButton               matlab.ui.control.Button
        SkipButton                matlab.ui.control.Button
        Panel_2                   matlab.ui.container.Panel
        SeriesnumberforthestressedstateLabel  matlab.ui.control.Label
        stressed_series_num       matlab.ui.control.Spinner
        WriteLabel_2              matlab.ui.control.Label
        SeriesnumberfortherelaxedstateLabel  matlab.ui.control.Label
        relaxed_series_num        matlab.ui.control.Spinner
        Panel_3                   matlab.ui.container.Panel
        SelectinputfileButton     matlab.ui.control.Button
        SelectoutputfolderButton  matlab.ui.control.Button
        inputFileText             matlab.ui.control.EditField
        outputFolderText          matlab.ui.control.EditField
        ExperimentnameLabel       matlab.ui.control.Label
        ExperimentnameEditField   matlab.ui.control.EditField
        Image2                    matlab.ui.control.Image
        CopyrightC2019UniversidaddeSevillaLabel  matlab.ui.control.Label
        CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, folder_names)
            movegui(app.UIFigure,"center");
            app.tableChannels.Data = {folder_names.cell,folder_names.beads,folder_names.fibers};
            setappdata(0,'folder_names',folder_names);
            %Create a flag in case the user closes the app
            setappdata(0,'flag_closeApp',true);
        end

        % Button pushed function: addColumnChannel
        function addColumnChannelButtonPushed(app, event)
            data=app.tableChannels.Data;
            column = size(data,2);
            column = column +1;
            data(:,column) = {''};
            app.tableChannels.Data = data;
            app.tableChannels.ColumnName{column} = ['Channel ',num2str(column-1)];
        end

        % Cell edit callback: tableChannels
        function tableChannelsCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            folder_names = getappdata(0,'folder_names');
            if not(strcmp(newData,folder_names.cell)) && ...
                    not(strcmp(newData,folder_names.beads)) &&...
                    not(strcmp(newData,folder_names.fibers)) &&...
                    not(strcmp(newData,folder_names.nuclei))
                app.tableChannels.Data{indices(1),indices(2)} = '';
                
            end
        end

        % Callback function
        function addColumnSeriesButtonPushed(app, event)
         

        end

        % Callback function
        function removeColumnChannelButtonPushed(app, event)
         
        end

        % Button pushed function: SelectinputfileButton
        function SelectinputfileButtonPushed(app, event)
            [file_name,file_path] = uigetfile('../','Please select your input .lif or .oif file');
            setappdata(0,'file_name',file_name);
            setappdata(0,'file_path',file_path);
            app.inputFileText.Value = [file_path filesep file_name];
            app.inputFileText.FontColor = 'k';
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            store_path = uigetdir('', 'Select a folder to store the tif images');
            setappdata(0,'store_path',store_path);
            app.outputFolderText.Value = store_path;
            app.outputFolderText.FontColor = 'k';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            assignin('base','file_path',getappdata(0,'file_path'));
            assignin('base','file_name',getappdata(0,'file_name'));
            assignin('base','store_path',getappdata(0,'store_path'));
            setappdata(0,'flag_closeApp',false);
            
            %Get the channel names
            channels = app.tableChannels.Data;
            %Define the channel ids based on the ones that are not empty
            channels_id = find(not(cellfun(@isempty,channels)));
            %Remove the empty channels
            param.channels_name = channels(channels_id);
            param.channels_id = channels_id;
            
            %Get the series names
            param.series_id_stressed = app.stressed_series_num.Value-1;
            param.series_id_relaxed = app.relaxed_series_num.Value-1;
            
            %Get the timepoints and the zplanes
            tmp = str2num(app.zplanes.Value);
            param.zPlanes = tmp(1):tmp(2);
            tmp = str2num(app.timepoints.Value);
            param.tPoints = tmp(1):tmp(2);
            tmp = str2num(app.xRange.Value);
            param.xRange = tmp(1):tmp(2);
            tmp = str2num(app.yRange.Value);
            param.yRange = tmp(1):tmp(2);
            clear tmp;
            
            %Get the name of the experiment
            param.experiment_name = app.ExperimentnameEditField.Value;
            
            %Store the data
            assignin('base','param',param);
            
            %Lift the flag to continue
            evalin('base', 'clear h_gui');
            evalin('base', 'flag_multitiff = true;');
            
            UIFigureCloseRequest(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %Pass the flag to determine if we have to close the app or not
            assignin('base','flag_closeApp',getappdata(0,'flag_closeApp'));
            delete(app)
        end

        % Button pushed function: SkipButton
        function SkipButtonPushed(app, event)
            setappdata(0,'flag_closeApp',false);
            %Lift the flag to continue
            UIFigureCloseRequest(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.addColumnChannel = uibutton(app.UIFigure, 'push');
            app.addColumnChannel.ButtonPushedFcn = createCallbackFcn(app, @addColumnChannelButtonPushed, true);
            app.addColumnChannel.Tooltip = {'Add channels to the table'};
            app.addColumnChannel.Text = '+';
            app.tableChannels = uitable(app.UIFigure);
            app.tableChannels.ColumnName = {'Channel 0'; 'Channel 1'; 'Channel 2'};
            app.tableChannels.RowName = {''};
            app.tableChannels.ColumnEditable = true;
            app.tableChannels.CellEditCallback = createCallbackFcn(app, @tableChannelsCellEdit, true);
            app.tableChannels.Tooltip = {'Specify which channel contains which type of data.'; 'You can only name them by: ''cell'''; ' ''beads'' ''fibers'' or ''nuclei'''};
            app.DefinethechannelnamesleaveemptyifyoudontwanttouseachannelLabel = uilabel(app.UIFigure);
            app.DefinethechannelnamesleaveemptyifyoudontwanttouseachannelLabel.Text = {'Define the channel names (leave empty if you don''t want to use a channel).'; 'Accepted names: cell, beads, fibers or nuclei:'};
            app.Panel = uipanel(app.UIFigure);
            app.ZrangetobekeptseparatedbycommasLabel = uilabel(app.Panel);
            app.ZrangetobekeptseparatedbycommasLabel.Text = 'Z range to be kept (separated by commas):';
            app.zplanes = uieditfield(app.Panel, 'text');
            app.zplanes.Tooltip = {'Z plane range that will be used. If you are not sure of how many Z planes your data has'; ' you can choose a high number for the upper limit. '};
            app.zplanes.Value = '1,1000';
            app.TimepointrangetobekeptseparatedbycommasEditFieldLabel = uilabel(app.Panel);
            app.TimepointrangetobekeptseparatedbycommasEditFieldLabel.Text = 'Time point range to be kept (separated by commas):';
            app.timepoints = uieditfield(app.Panel, 'text');
            app.timepoints.Tooltip = {'Time point range that will be used. If you are not sure of how many time points your data has'; ' you can choose a high number for the upper limit.'};
            app.timepoints.Value = '1,1000';
            app.WriteLabel = uilabel(app.Panel);
            app.WriteLabel.Text = {'Choose a high upper limit'; ' to get the entire stack'};
            app.XrangetobekeptseparatedbycommasLabel = uilabel(app.Panel);
            app.XrangetobekeptseparatedbycommasLabel.Text = 'X range to be kept (separated by commas):';
            app.xRange = uieditfield(app.Panel, 'text');
            app.xRange.Tooltip = {'X range that will be used. If you are not sure of how many X voxels your data has you can choose a high number for the upper limit.'};
            app.xRange.Value = '1,10000';
            app.YrangetobekeptseparatedbycommasLabel = uilabel(app.Panel);
            app.YrangetobekeptseparatedbycommasLabel.Text = 'Y range to be kept (separated by commas):';
            app.yRange = uieditfield(app.Panel, 'text');
            app.yRange.Tooltip = {'Y range that will be used. If you are not sure of how many Y voxels your data has you can choose a high number for the upper limit.'};
            app.yRange.Value = '1,10000';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Start the data file extraction.'};
            app.StartButton.Text = 'Start';
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @SkipButtonPushed, true);
            app.SkipButton.Tooltip = {'Skip this step by selecting existing raw data files.'};
            app.SkipButton.Text = 'Skip this step >>';
            app.Panel_2 = uipanel(app.UIFigure);
            app.SeriesnumberforthestressedstateLabel = uilabel(app.Panel_2);
            app.SeriesnumberforthestressedstateLabel.Text = 'Series number for the ''stressed'' state:';
            app.stressed_series_num = uispinner(app.Panel_2);
            app.stressed_series_num.Limits = [1 Inf];
            app.stressed_series_num.Tooltip = {'Series number of your raw data that corresponds to your ''stressed'' state (use same indexing as in ImageJ). '; ''};
            app.stressed_series_num.Value = 1;
            app.WriteLabel_2 = uilabel(app.Panel_2);
            app.WriteLabel_2.Text = 'Set to 0 if it''s included in the ''stressed'' series as the last time point';
            app.SeriesnumberfortherelaxedstateLabel = uilabel(app.Panel_2);
            app.SeriesnumberfortherelaxedstateLabel.Text = 'Series number for the ''relaxed'' state:';
            app.relaxed_series_num = uispinner(app.Panel_2);
            app.relaxed_series_num.Limits = [0 Inf];
            app.relaxed_series_num.Tooltip = {'Series number of your raw data that corresponds to your ''relaxed'' state. Use same indexing as in ImageJ. '; 'If your ''relaxed'' state is included in  your ''stressed'' series as the last time point'; ''; ' set to -1.'};
            app.relaxed_series_num.Value = 1;
            app.Panel_3 = uipanel(app.UIFigure);
            app.SelectinputfileButton = uibutton(app.Panel_3, 'push');
            app.SelectinputfileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectinputfileButtonPushed, true);
            app.SelectinputfileButton.Text = 'Select input file...';
            app.SelectoutputfolderButton = uibutton(app.Panel_3, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Text = 'Select output folder...';
            app.inputFileText = uieditfield(app.Panel_3, 'text');
            app.inputFileText.Tooltip = {'Full path to the microscopy input file'};
            app.inputFileText.Value = 'File not selected';
            app.outputFolderText = uieditfield(app.Panel_3, 'text');
            app.outputFolderText.Tooltip = {'Full path to the output folder'};
            app.outputFolderText.Value = 'Folder not selected';
            app.ExperimentnameLabel = uilabel(app.Panel_3);
            app.ExperimentnameLabel.Text = 'Experiment name:';
            app.ExperimentnameEditField = uieditfield(app.Panel_3, 'text');
            app.ExperimentnameEditField.Tooltip = {'The software will create a results folder with this name. It will be located in the selected output folder.'};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'tfm_lab_logo.png';
            app.CopyrightC2019UniversidaddeSevillaLabel = uilabel(app.UIFigure);
            app.CopyrightC2019UniversidaddeSevillaLabel.Text = 'Copyright (C) 2020 Escuela Técnica Superior de Ingeniería, Universidad de Sevilla (Spain)';
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel = uilabel(app.UIFigure);
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel.Text = 'Copyright (C) 2020 Department of Mechanical Engineering, Biomechanics section, KU Leuven (Belgium)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_inputs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end