classdef set_shiftCorrection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AxesOverlay                     matlab.ui.control.UIAxes
        ShiftcorrectionparametersPanel  matlab.ui.container.Panel
        StrategyLabel                   matlab.ui.control.Label
        Strategy                        matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        Method                          matlab.ui.control.DropDown
        ContinueButton                  matlab.ui.control.Button
        ZslicesSlider_3Label            matlab.ui.control.Label
        Zslices                         matlab.ui.control.Slider
        SkipButton                      matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        CopyrightC2019UniversidaddeSevillaLabel  matlab.ui.control.Label
        CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, store_path, folder_names, experiment_name)
            movegui(app.UIFigure,"center");
            %Read the channels
            folders = dir([store_path filesep experiment_name filesep folder_names.tiff_filtered]);
            folders = folders(3:end);
            
            %Create a flag to know if there are fibers
            flag_fibers = false;
            for ii = 1:length(folders)
                if strcmp(folders(ii).name, 'fibers')
                    flag_fibers = true;
                end
            end
            
            %Get the files 
            if flag_fibers                
                files = dir([store_path filesep experiment_name filesep folder_names.tiff_filtered filesep 'fibers']);
            else
                files = dir([store_path filesep experiment_name filesep folder_names.tiff_filtered filesep 'beads']);
            end
            files = files(3:end);   
             %load the first timepoint
            im = load([files(1).folder filesep files(1).name]);
            %Load the reference timepoint
            imR = load([files(end).folder filesep files(end).name]);
            
            %Adapt the zslice scroller
            app.Zslices.Limits = [1 size(im.im,3)];
            
            setappdata(0,'im',im.im);
            setappdata(0,'imR',imR.im);
            setappdata(0,'flag_fibers',flag_fibers);
            
            slice = round(app.Zslices.Value);
            %Create an overlayed image
            tmp = imfuse(imR.im(:,:,slice),im.im(:,:,slice),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
             assignin('base','strategy',app.Strategy.Value);
             assignin('base','method',app.Method.Value);
             flag_fibers = getappdata(0,'flag_fibers');
             assignin('base','flag_fibers',flag_fibers);
             
             UIFigureCloseRequest(app, event);
        end

        % Callback function: SkipButton, UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Value changed function: Zslices
        function ZslicesValueChanged(app, event)
            slice = round(app.Zslices.Value);
            im = getappdata(0,'im');
            imR = getappdata(0,'imR');
            %Create an overlayed image
            tmp = imfuse(imR(:,:,slice),im(:,:,slice),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);
        end

        % Callback function
        function SkipButtonPushed(app, event)
            UIFigureCloseRequest(app, event);
        end

        % Value changing function: Zslices
        function ZslicesValueChanging(app, event)
            changingValue = round(event.Value);
            im = getappdata(0,'im');
            imR = getappdata(0,'imR');
            %Create an overlayed image
            tmp = imfuse(imR(:,:,changingValue),im(:,:,changingValue),'falsecolor','Scaling','joint','ColorChannels',[1 2 0]);
            imshow(tmp,'Parent',app.AxesOverlay);
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            %Compute the new scrolling value
            new_val = round(app.Zslices.Value - verticalScrollCount);
            %Check that is still within the allowed limits
            if  ismember(new_val,app.Zslices.Limits(1):app.Zslices.Limits(2))
                app.Zslices.Value = new_val;
            end
            %Make the slices change
            ZslicesValueChanged(app,event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.AxesOverlay = uiaxes(app.UIFigure);
            title(app.AxesOverlay, 'Overlay')
            xlabel(app.AxesOverlay, '')
            ylabel(app.AxesOverlay, '')
            app.ShiftcorrectionparametersPanel = uipanel(app.UIFigure);
            app.ShiftcorrectionparametersPanel.Title = 'Shift correction parameters';
            app.StrategyLabel = uilabel(app.ShiftcorrectionparametersPanel);
            app.StrategyLabel.Text = 'Strategy:';
            app.Strategy = uidropdown(app.ShiftcorrectionparametersPanel);
            app.Strategy.Items = {'direct', 'seq', ''};
            app.Strategy.Tooltip = {'Strategy for shift correction.'; '''direct'': (recommended) all the time points are corrected using the middle time point as a reference.'; ''; '''sequential'': all the time points are corrected using the previous time point as a reference. A last step is done to express all the time lapse in the same coordinate syste. (Use only if very large shifs happen in a long time lapse.)'};
            app.Strategy.Value = 'direct';
            app.MethodLabel = uilabel(app.ShiftcorrectionparametersPanel);
            app.MethodLabel.Text = 'Method:';
            app.Method = uidropdown(app.ShiftcorrectionparametersPanel);
            app.Method.Items = {'Translation phaseCorr (fast)', 'Translation Elastix (medium)', 'Euler Elastix (slow)'};
            app.Method.Value = 'Translation phaseCorr (fast)';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue....';
            app.ZslicesSlider_3Label = uilabel(app.UIFigure);
            app.ZslicesSlider_3Label.Text = 'Z-slices';
            app.Zslices = uislider(app.UIFigure);
            app.Zslices.Limits = [1 5];
            app.Zslices.ValueChangedFcn = createCallbackFcn(app, @ZslicesValueChanged, true);
            app.Zslices.ValueChangingFcn = createCallbackFcn(app, @ZslicesValueChanging, true);
            app.Zslices.MinorTicks = [];
            app.Zslices.Tooltip = {'Use the mouse wheel to scroll through the image slices.'};
            app.Zslices.Value = 1;
            app.SkipButton = uibutton(app.UIFigure, 'push');
            app.SkipButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SkipButton.Text = 'Skip this step >>';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'tfm_lab_logo.png';
            app.CopyrightC2019UniversidaddeSevillaLabel = uilabel(app.UIFigure);
            app.CopyrightC2019UniversidaddeSevillaLabel.Text = 'Copyright (C) 2020 Escuela Técnica Superior de Ingeniería, Universidad de Sevilla (Spain)';
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel = uilabel(app.UIFigure);
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel.Text = 'Copyright (C) 2020 Department of Mechanical Engineering, Biomechanics section, KU Leuven (Belgium)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_shiftCorrection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end