classdef set_tracCalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ECMmechanicalparametersPanel    matlab.ui.container.Panel
        ElasticmodulusPaSpinnerLabel    matlab.ui.control.Label
        ElasticmodulusPaSpinner         matlab.ui.control.Spinner
        PoissonsratioSpinnerLabel       matlab.ui.control.Label
        PoissonsratioSpinner            matlab.ui.control.Spinner
        HydrogelbehaviorDropDownLabel   matlab.ui.control.Label
        HydrogelbehaviorDropDown        matlab.ui.control.DropDown
        ConstitutivemodelonlyfornonlinearbehaviorLabel  matlab.ui.control.Label
        ConstitutivemodelonlyfornonlinearbehaviorDropDown  matlab.ui.control.DropDown
        DisplacementfieldmarkerLabel    matlab.ui.control.Label
        DisplacementfieldmarkerDropDown  matlab.ui.control.DropDown
        CellandECMmeshingPanel          matlab.ui.container.Panel
        ElementsizecellSpinnerLabel     matlab.ui.control.Label
        ElementsizecellSpinner          matlab.ui.control.Spinner
        ElementsizeECMSpinnerLabel      matlab.ui.control.Label
        ElementsizeECMSpinner           matlab.ui.control.Spinner
        Button                          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        NumitersmoothingSpinnerLabel    matlab.ui.control.Label
        NumitersmoothingSpinner         matlab.ui.control.Spinner
        smoothingMethod                 matlab.ui.control.DropDown
        smoothingAmount                 matlab.ui.control.Spinner
        PresmoothingLabel               matlab.ui.control.Label
        PresmoothingSpinner             matlab.ui.control.Spinner
        Button_seeSmoothing             matlab.ui.control.Button
        SmoothingmethodLabel            matlab.ui.control.Label
        AmountsmoothingLabel            matlab.ui.control.Label
        MeshrefinementCheckBox          matlab.ui.control.CheckBox
        Image2                          matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        CopyrightC2019UniversidaddeSevillaLabel  matlab.ui.control.Label
        CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel  matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        SolverparametersPanel           matlab.ui.container.Panel
        ElementsizeECMSpinnerLabel_2    matlab.ui.control.Label
        initialTimeIncrement            matlab.ui.control.Spinner
        NumitersmoothingSpinnerLabel_2  matlab.ui.control.Label
        minTimeIncrement                matlab.ui.control.Spinner
        elementType                     matlab.ui.control.DropDown
        ElementtypeLabel                matlab.ui.control.Label
        NumitersmoothingSpinnerLabel_3  matlab.ui.control.Label
        solver                          matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, path_names, folder_names, param, file_names)
            movegui(app.UIFigure,"center");
            
            res = getResolution(folder_names.tiff_raw);
            
            %Check how many channels are there
            channels = dir([path_names.store_path filesep param.experiment_name filesep folder_names.post_shift_corrected]);
            channels = channels(3:end);
            tmp_ids = [channels(:).isdir];
            channels = channels(tmp_ids);
            clear tmp_ids cell_seg;
            
            %Create a variable to store the cell segmentations of each channel
            cell_seg.beads = [];
            cell_seg.fibers = [];
            for ii=1:length(channels)
                tmp = load([path_names.store_path filesep param.experiment_name filesep folder_names.post_shift_corrected filesep channels(ii).name filesep folder_names.cellSeg filesep 'tp_01.mat'],'cell_seg');
                
                %Load the cropping rectangle
                load([path_names.store_path filesep param.experiment_name filesep folder_names.results_for_paraview filesep channels(ii).name filesep file_names.paraview_param],'crop_rect');
                
                %Crop and store the cell segmentation
                cell_seg.(channels(ii).name) = tmp.cell_seg(crop_rect(2):crop_rect(2)+crop_rect(4)-1,crop_rect(1):crop_rect(1)+crop_rect(3)-1,:);
                clear tmp crop_rect;
            end
            
            %Create a smooth version of the mask - initialize it as same as
            %mask
            cell_seg_smooth = cell_seg;
            
            setappdata(0,'cell_seg',cell_seg);
            setappdata(0,'cell_seg_smooth',cell_seg_smooth);
            setappdata(0,'folder_names',folder_names);
            setappdata(0,'res',res);
            
            %Introduce the channel names in the channel selector
            for ii=1:length(channels)
                app.DisplacementfieldmarkerDropDown.Items{ii} = channels(ii).name;
            end
        end

        % Callback function
        function SkipButton_2Pushed(app, event)
            assignin('base','need_to_load','parameters');
            UIFigureCloseRequest(app, event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Callback function
        function SkipButtonPushed(app, event)
            assignin('base','need_to_load','images');
            UIFigureCloseRequest(app, event);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            %Material properties
            mech_props.elastic_modulus = app.ElasticmodulusPaSpinner.Value;
            mech_props.poissons_ratio = app.PoissonsratioSpinner.Value;
            mech_props.ecm_behavior = app.HydrogelbehaviorDropDown.Value;
            mech_props.constitutive_model = app.ConstitutivemodelonlyfornonlinearbehaviorDropDown.Value;
            %Abaqus parameters
            mech_props.abaqus.element_type = app.elementType.Value;
            mech_props.abaqus.initial_increment = app.initialTimeIncrement.Value;
            mech_props.abaqus.min_increment = app.minTimeIncrement.Value;
            mech_props.solver = app.solver.Value;
            %Mesh parameters
            mesh_options.selected_channel = app.DisplacementfieldmarkerDropDown.Value;
            mesh_options.pre_smoothing = app.PresmoothingSpinner.Value;
            mesh_options.maxvol_gel = app.ElementsizeECMSpinner.Value;
            mesh_options.maxsize_cell = app.ElementsizecellSpinner.Value;
            mesh_options.post_smoothing = app.NumitersmoothingSpinner.Value;
            mesh_options.post_smoothing_method = app.smoothingMethod.Value;
            mesh_options.post_smoothing_amount = app.smoothingAmount.Value;
            mesh_options.refine_flag = app.MeshrefinementCheckBox.Value;


            assignin('base','mech_props',mech_props);
            assignin('base','mesh_options',mesh_options);
            
            UIFigureCloseRequest(app, event);
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            %When the user presses this button, is just a trial, so we
            %leave the write options empty
            write_options.write_path = [];
            mesh_options.maxvol_gel = app.ElementsizeECMSpinner.Value;
            mesh_options.maxsize_cell = app.ElementsizecellSpinner.Value;
            mesh_options.post_smoothing = app.NumitersmoothingSpinner.Value;
            mesh_options.post_smoothing_method = app.smoothingMethod.Value;
            mesh_options.post_smoothing_amount = app.smoothingAmount.Value;
            folder_names = getappdata(0,'folder_names');
            res = getResolution(folder_names.tiff_raw);
            
            %Get the mask corresponding to the selected marker
            selected_channel = app.DisplacementfieldmarkerDropDown.Value;
            tmp = getappdata(0,'cell_seg_smooth');
            cell_seg = tmp.(selected_channel);
            
            d = uiprogressdlg(app.UIFigure,'Title','Meshing cell...',...
                'Indeterminate','on');
                        
            [node,elem0,elem,faces,mesh_options] = getMeshedVolume(cell_seg,res(1),res(2),res(3),mesh_options,write_options);


            % close the dialog box
            
            cla(app.UIAxes);
            
            hold(app.UIAxes,'on')
%             h=plotmesh(node,faces,[elem;elem0],'Parent',app.UIAxes);
            h=plotmesh(node,faces,'Parent',app.UIAxes);
            h(2).FaceColor = [1 0.5 0.5];
            h(2).FaceAlpha = 0.2;
            h(1).FaceColor = [0.5 1 0.5];
            daspect(app.UIAxes, [1 1 1]);
            close;
            title(app.UIAxes, {'RESULTANT ELEMENTS :',...
                ['N_{elements} hydrogel = ' num2str(size(elem0,1))]...
                ['N_{elements} cell = ' num2str(size(elem,1))],...
                ['N_{elements} TOTAL = ' num2str(size(elem,1)+size(elem0,1)) ]} );
            hold(app.UIAxes,'off')
            close(d);
            
        end

        % Button pushed function: Button_seeSmoothing
        function Button_seeSmoothingPushed(app, event)
            
            cell_seg = getappdata(0,'cell_seg');
            res = getappdata(0,'res');
            
            selected_channel = app.DisplacementfieldmarkerDropDown.Value;
            
            if app.PresmoothingSpinner.Value > 0
                %Smooth the mask
                N=app.PresmoothingSpinner.Value;
                kernel = ones(N, N, N) / N^3;
                blurryImage = convn(double(cell_seg.(selected_channel)), kernel, 'same');
                
                %Make it binary again
                cell_seg.(selected_channel) = uint8(blurryImage > 0.5);
            end
            
            figure
            hpat = PATCH_3Darray(cell_seg.(selected_channel),(1:size(cell_seg.(selected_channel),1))*res(1),...
                (1:size(cell_seg.(selected_channel),2))*res(2),...
                (1:size(cell_seg.(selected_channel),3))*res(3));
            
            hpat.FaceAlpha = 0.2; hpat.EdgeAlpha = 0.4;
            title(['Smoothing value = ', num2str(app.PresmoothingSpinner.Value)]);
            set(gca,'FontName','Segoe UI Light')
            
            setappdata(0,'cell_seg_smooth',cell_seg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ECMmechanicalparametersPanel = uipanel(app.UIFigure);
            app.ECMmechanicalparametersPanel.Title = 'ECM mechanical parameters:';
            app.ElasticmodulusPaSpinnerLabel = uilabel(app.ECMmechanicalparametersPanel);
            app.ElasticmodulusPaSpinnerLabel.Text = 'Elastic modulus (Pa): ';
            app.ElasticmodulusPaSpinner = uispinner(app.ECMmechanicalparametersPanel);
            app.ElasticmodulusPaSpinner.Limits = [1 Inf];
            app.ElasticmodulusPaSpinner.Tooltip = {'Elastic modulus of the hydrogel.'};
            app.ElasticmodulusPaSpinner.Value = 200;
            app.PoissonsratioSpinnerLabel = uilabel(app.ECMmechanicalparametersPanel);
            app.PoissonsratioSpinnerLabel.Text = 'Poisson''s ratio:';
            app.PoissonsratioSpinner = uispinner(app.ECMmechanicalparametersPanel);
            app.PoissonsratioSpinner.Step = 0.01;
            app.PoissonsratioSpinner.Limits = [0 10];
            app.PoissonsratioSpinner.Tooltip = {'Poisson''s ratio of the hydrogel.'};
            app.PoissonsratioSpinner.Value = 0.49;
            app.HydrogelbehaviorDropDownLabel = uilabel(app.ECMmechanicalparametersPanel);
            app.HydrogelbehaviorDropDownLabel.Enable = 'off';
            app.HydrogelbehaviorDropDownLabel.Text = 'Hydrogel behavior:';
            app.HydrogelbehaviorDropDown = uidropdown(app.ECMmechanicalparametersPanel);
            app.HydrogelbehaviorDropDown.Items = {'Linear elastic', 'Non linear elastic'};
            app.HydrogelbehaviorDropDown.Enable = 'off';
            app.HydrogelbehaviorDropDown.Tooltip = {'Mechanical behavior of the hydrogel.'};
            app.HydrogelbehaviorDropDown.Value = 'Linear elastic';
            app.ConstitutivemodelonlyfornonlinearbehaviorLabel = uilabel(app.ECMmechanicalparametersPanel);
            app.ConstitutivemodelonlyfornonlinearbehaviorLabel.Enable = 'off';
            app.ConstitutivemodelonlyfornonlinearbehaviorLabel.Text = {'Constitutive model '; '(only for non-linear behavior):'};
            app.ConstitutivemodelonlyfornonlinearbehaviorDropDown = uidropdown(app.ECMmechanicalparametersPanel);
            app.ConstitutivemodelonlyfornonlinearbehaviorDropDown.Items = {'Neo-hookean'};
            app.ConstitutivemodelonlyfornonlinearbehaviorDropDown.Enable = 'off';
            app.ConstitutivemodelonlyfornonlinearbehaviorDropDown.Tooltip = {'Constitutive model of the hydrogel. Please use only if the hydrogel''s behavior is nonlinear elastic.'};
            app.ConstitutivemodelonlyfornonlinearbehaviorDropDown.Value = 'Neo-hookean';
            app.DisplacementfieldmarkerLabel = uilabel(app.ECMmechanicalparametersPanel);
            app.DisplacementfieldmarkerLabel.Text = 'Displacement field marker:';
            app.DisplacementfieldmarkerDropDown = uidropdown(app.ECMmechanicalparametersPanel);
            app.DisplacementfieldmarkerDropDown.Items = {};
            app.DisplacementfieldmarkerDropDown.Tooltip = {'Whcih marker was used for displacement calculation.'};
            app.DisplacementfieldmarkerDropDown.Value = {};
            app.CellandECMmeshingPanel = uipanel(app.UIFigure);
            app.CellandECMmeshingPanel.Title = 'Cell and ECM meshing:';
            app.ElementsizecellSpinnerLabel = uilabel(app.CellandECMmeshingPanel);
            app.ElementsizecellSpinnerLabel.Text = 'Element size cell:';
            app.ElementsizecellSpinner = uispinner(app.CellandECMmeshingPanel);
            app.ElementsizecellSpinner.Step = 0.1;
            app.ElementsizecellSpinner.Limits = [0.1 Inf];
            app.ElementsizecellSpinner.Tooltip = {'Maximum radius of the Delaunay sphere (element size) that is allowed for the cell surface meshing.'};
            app.ElementsizecellSpinner.Value = 2;
            app.ElementsizeECMSpinnerLabel = uilabel(app.CellandECMmeshingPanel);
            app.ElementsizeECMSpinnerLabel.Text = 'Element size ECM:';
            app.ElementsizeECMSpinner = uispinner(app.CellandECMmeshingPanel);
            app.ElementsizeECMSpinner.Step = 50;
            app.ElementsizeECMSpinner.Limits = [0 Inf];
            app.ElementsizeECMSpinner.Tooltip = {'Maximum tetrahedra element volume that is allowed for the ECM meshing.'};
            app.ElementsizeECMSpinner.Value = 200;
            app.Button = uibutton(app.CellandECMmeshingPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'iconfinder_119_Eye_183245.png';
            app.Button.Tooltip = {'Visualize mesh.'};
            app.Button.Text = '';
            app.Label = uilabel(app.CellandECMmeshingPanel);
            app.Label.Text = 'Recommendation: total #elements < 230000.';
            app.NumitersmoothingSpinnerLabel = uilabel(app.CellandECMmeshingPanel);
            app.NumitersmoothingSpinnerLabel.Text = 'Num. iter. smoothing:';
            app.NumitersmoothingSpinner = uispinner(app.CellandECMmeshingPanel);
            app.NumitersmoothingSpinner.Limits = [0 Inf];
            app.NumitersmoothingSpinner.Tooltip = {'Amount of smoothing after meshing. Use it to remove spikes in the surface. Set to 0 if no smoothing is required.'};
            app.NumitersmoothingSpinner.Value = 2;
            app.smoothingMethod = uidropdown(app.CellandECMmeshingPanel);
            app.smoothingMethod.Items = {'lowpass', 'laplacian', ''};
            app.smoothingMethod.Tooltip = {'smoothing method'};
            app.smoothingMethod.Value = 'lowpass';
            app.smoothingAmount = uispinner(app.CellandECMmeshingPanel);
            app.smoothingAmount.Step = 0.1;
            app.smoothingAmount.Limits = [0 1];
            app.smoothingAmount.Tooltip = {'Amont of smoothing per iteration'};
            app.PresmoothingLabel = uilabel(app.CellandECMmeshingPanel);
            app.PresmoothingLabel.Text = 'Pre smoothing:';
            app.PresmoothingSpinner = uispinner(app.CellandECMmeshingPanel);
            app.PresmoothingSpinner.Limits = [0 Inf];
            app.PresmoothingSpinner.Tooltip = {'Amount of mask smoothing before meshing. Use it to remove spikes in the surface. Set to 0 if no smoothing is required.'};
            app.Button_seeSmoothing = uibutton(app.CellandECMmeshingPanel, 'push');
            app.Button_seeSmoothing.ButtonPushedFcn = createCallbackFcn(app, @Button_seeSmoothingPushed, true);
            app.Button_seeSmoothing.Icon = 'iconfinder_119_Eye_183245.png';
            app.Button_seeSmoothing.Tooltip = {'Visualize smoothed mask.'};
            app.Button_seeSmoothing.Text = '';
            app.SmoothingmethodLabel = uilabel(app.CellandECMmeshingPanel);
            app.SmoothingmethodLabel.Text = 'Smoothing method:';
            app.AmountsmoothingLabel = uilabel(app.CellandECMmeshingPanel);
            app.AmountsmoothingLabel.Text = 'Amount smoothing:';
            app.MeshrefinementCheckBox = uicheckbox(app.CellandECMmeshingPanel);
            app.MeshrefinementCheckBox.Enable = 'off';
            app.MeshrefinementCheckBox.Text = 'Mesh refinement';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'tfm_lab_logo.png';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Meshed volume')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.CopyrightC2019UniversidaddeSevillaLabel = uilabel(app.UIFigure);
            app.CopyrightC2019UniversidaddeSevillaLabel.Text = 'Copyright (C) 2020 Escuela Técnica Superior de Ingeniería, Universidad de Sevilla (Spain)';
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel = uilabel(app.UIFigure);
            app.CopyrightC2019BmeDepMechEngineeringKUleuvenBelgiumLabel.Text = 'Copyright (C) 2020 Department of Mechanical Engineering, Biomechanics section, KU Leuven (Belgium)';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tooltip = {'Run the traction recovery.'};
            app.StartButton.Text = 'Start';
            app.SolverparametersPanel = uipanel(app.UIFigure);
            app.SolverparametersPanel.Title = 'Solver parameters:';
            app.ElementsizeECMSpinnerLabel_2 = uilabel(app.SolverparametersPanel);
            app.ElementsizeECMSpinnerLabel_2.Enable = 'off';
            app.ElementsizeECMSpinnerLabel_2.Text = 'Initial time increment:';
            app.initialTimeIncrement = uispinner(app.SolverparametersPanel);
            app.initialTimeIncrement.Step = 0.01;
            app.initialTimeIncrement.Limits = [1e-05 1];
            app.initialTimeIncrement.Enable = 'off';
            app.initialTimeIncrement.Tooltip = {'Initial time increment for Abaqus''  general static analysis.'};
            app.initialTimeIncrement.Value = 1;
            app.NumitersmoothingSpinnerLabel_2 = uilabel(app.SolverparametersPanel);
            app.NumitersmoothingSpinnerLabel_2.Enable = 'off';
            app.NumitersmoothingSpinnerLabel_2.Tooltip = {''};
            app.NumitersmoothingSpinnerLabel_2.Text = 'Min. time increment:';
            app.minTimeIncrement = uispinner(app.SolverparametersPanel);
            app.minTimeIncrement.Step = 0.01;
            app.minTimeIncrement.Limits = [1e-05 1];
            app.minTimeIncrement.Enable = 'off';
            app.minTimeIncrement.Tooltip = {'Minimum time increment allowed for Abaqus''  general static analysis.'};
            app.minTimeIncrement.Value = 0.05;
            app.elementType = uidropdown(app.SolverparametersPanel);
            app.elementType.Items = {'C3D4', 'C3D10', '', ''};
            app.elementType.Enable = 'off';
            app.elementType.Tooltip = {'smoothing method'};
            app.elementType.Value = 'C3D4';
            app.ElementtypeLabel = uilabel(app.SolverparametersPanel);
            app.ElementtypeLabel.Enable = 'off';
            app.ElementtypeLabel.Text = 'Element type:';
            app.NumitersmoothingSpinnerLabel_3 = uilabel(app.SolverparametersPanel);
            app.NumitersmoothingSpinnerLabel_3.Tooltip = {''};
            app.NumitersmoothingSpinnerLabel_3.Text = 'Solver:';
            app.solver = uidropdown(app.SolverparametersPanel);
            app.solver.Items = {'Abaqus', 'FreeFEM', '', ''};
            app.solver.Tooltip = {'Name of the solver to be used.'; 'FreeFem only available for linear elasticity.'};
            app.solver.Value = 'Abaqus';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = set_tracCalc(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end